classdef pid_david < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        muestra3                     matlab.ui.control.NumericEditField
        muestra2                     matlab.ui.control.NumericEditField
        muestra1                     matlab.ui.control.NumericEditField
        stop                         matlab.ui.control.Button
        monitor_serial               matlab.ui.control.EditField
        monitorserialEditFieldLabel  matlab.ui.control.Label
        inicio                       matlab.ui.control.Button
        monitor                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: inicio
        function inicioButtonPushed(app, event)
            delete(instrfind({'Port'},{'COM1'}));
            device=serial('COM1','BaudRate',9600,'Terminator','CR/LF');
            fopen(device);
            mat=zeros(1,1000);
            
          %configureTerminator(device, 'CR/LF');
            i=1;
            
               hold(app.monitor);
            while(1)
                a=fscanf(device,'%f,%f,%f\r\n');
               app.muestra1.Value=a(1);
               app.muestra2.Value=a(2);
               app.muestra3.Value=a(3);
               
               data(i)=a(1);
                T(i) = a(2);
                Ouput(i)=a(3);
               
              %  Output(a) = fscanf(device,'%f');
                
                plot(app.monitor,data);
                plot(app.monitor,T,'r');
                plot(app.monitor,Ouput,'b');
                 
        
                i=i+1;
                pause(0.1);
            
            end
        end

        % Button pushed function: stop
        function stopButtonPushed(app, event)
            app.delete;           
            fclose(device);
            delete(device);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.monitor = uiaxes(app.UIFigure);
            title(app.monitor, 'GRAFICA PID')
            xlabel(app.monitor, 'TIEMPO')
            ylabel(app.monitor, 'TEMPERATURA')
            app.inicio = uibutton(app.UIFigure, 'push');
            app.inicio.ButtonPushedFcn = createCallbackFcn(app, @inicioButtonPushed, true);
            app.inicio.Text = 'inicio';
            app.monitorserialEditFieldLabel = uilabel(app.UIFigure);
            app.monitorserialEditFieldLabel.Text = 'monitor serial';
            app.monitor_serial = uieditfield(app.UIFigure, 'text');
            app.stop = uibutton(app.UIFigure, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stop.Text = 'stop';
            app.muestra1 = uieditfield(app.UIFigure, 'numeric');
            app.muestra2 = uieditfield(app.UIFigure, 'numeric');
            app.muestra3 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pid_david

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end